classdef project_optimized < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout2                   matlab.ui.container.GridLayout
        VelocidadinicialSpinnerLabel  matlab.ui.control.Label
        VelocidadinicialSpinner       matlab.ui.control.Spinner
        MasadelvagnSpinner_2Label     matlab.ui.control.Label
        MasadelvagnSpinner            matlab.ui.control.Spinner
        CoeficientedefriccindinmicoSpinnerLabel  matlab.ui.control.Label
        CoeficientedefriccindinmicoSpinner  matlab.ui.control.Spinner
        ValoresLabel                  matlab.ui.control.Label
        UnidadesLabel                 matlab.ui.control.Label
        CoeficientebarrastreSpinnerLabel  matlab.ui.control.Label
        CoeficientebarrastreSpinner   matlab.ui.control.Spinner
        msLabel                       matlab.ui.control.Label
        kilogramosLabel_2             matlab.ui.control.Label
        ComenzarButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        DetenerButton                 matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        dtSpinnerLabel                matlab.ui.control.Label
        dtSpinner                     matlab.ui.control.Spinner
        gravedadSpinnerLabel          matlab.ui.control.Label
        gravedadSpinner               matlab.ui.control.Spinner
        segundosLabel                 matlab.ui.control.Label
        ms2Label                      matlab.ui.control.Label
        XinicialSpinnerLabel          matlab.ui.control.Label
        XinicialSpinner               matlab.ui.control.Spinner
        metrosLabel                   matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        stop_animations = 0; % flag to stop animations
        X = linspace(0, 54);
        gravity = 0;
        uk_coeff = 0;
        k_coeff = 0;
        mass = 0;
        dt = 0;
    end
    
    methods (Access = private)
        
        function result = customHeaviside(~, x, subindex)
            result = subindex <= x;
        end
        
        function y = fn(app, x)
            y = (customHeaviside(app, x, -5) - customHeaviside(app, x, 12)) .* f(app, x) + (customHeaviside(app, x, 12) - customHeaviside(app, x, 35)) .* u(app, x) + (customHeaviside(app, x, 35) - customHeaviside(app, x, 60)) .* g(app, x);
            
            % TEST REALITY
            %y = (customHeaviside(app, x, 15) - customHeaviside(app, x, 20)) .* u(app, x);
            
            %y = (customHeaviside(app, x, -5) - customHeaviside(app, x, 4.28)) * 0 + (customHeaviside(app, x, 4.28) - customHeaviside(app, x, 7.85)) * (0.7 * x - 3) + (customHeaviside(app, x, 7.85) - customHeaviside(app, x, 60)) * 2.5;
        end
        
        function m = Dfn(app, x)
            m = (customHeaviside(app, x, -5) - customHeaviside(app, x, 12)) .* Df(app, x) + (customHeaviside(app, x, 12) - customHeaviside(app, x, 35)) .* Du(app, x) + (customHeaviside(app, x, 35) - customHeaviside(app, x, 60)) .* Dg(app, x);
            
            % TEST REALITY
            %m = (customHeaviside(app, x, 15) - customHeaviside(app, x, 20)) .* Du(app, x);
            
            %m = (customHeaviside(app, x, -5) - customHeaviside(app, x, 4.28)) * 0 + (customHeaviside(app, x, 4.28) - customHeaviside(app, x, 7.85)) * 0.7 * x + (customHeaviside(app, x, 7.85) - customHeaviside(app, x, 60)) * 0;
        end
        
        function m = DDfn(app, x)
            
            DDf = -5/12;
            m = (customHeaviside(app, x, -5) - customHeaviside(app, x, 12)) .* DDf + (customHeaviside(app, x, 12) - customHeaviside(app, x, 35)) .* DDu(app, x) + (customHeaviside(app, x, 35) - customHeaviside(app, x, 60)) .* DDg(app, x);
            
            % TEST REALITY
            %m = (customHeaviside(app, x, 15) - customHeaviside(app, x, 20)) .* DDu(app, x);
            
            %m = (customHeaviside(app, x, -5) - customHeaviside(app, x, 4.28)) * 0 + (customHeaviside(app, x, 4.28) - customHeaviside(app, x, 7.85)) * 0 + (customHeaviside(app, x, 7.85) - customHeaviside(app, x, 60)) * 0;
        end
        
        function y = f(~, x)
            %f_coefficients = [-5/24 35/12 30];
            y = -5/24 .* x .^ 2 + 35/12 .* x + 30;
        end
        function y = u(~, x)
            %u_coefficients = [900500247072012281167/168331151358618085883904 -1432428825299147450741/5260348479956815183872 22524958276928016111259/10520696959913630367744 17217098719842417040971/438362373329734598656];
            %y = 0.005349 .* x.^3 -0.272306 .* x.^2 + 2.141013 .* x + 39.275950;
            y = 900500247072012281167/168331151358618085883904 .* x.^3 -1432428825299147450741/5260348479956815183872 .* x.^2 + 22524958276928016111259/10520696959913630367744 .* x + 17217098719842417040971/438362373329734598656;
        end
        function y = g(~, x)
            %g_coefficients = [7426928963158243/4611686018427387904 -1454918324843309/4503599627370496 2735200034075743/140737488355328 -6043303342462469/17592186044416];
            %y = 0.001610 .* x.^3 -0.323156 .* x.^2 + 19.434765 .* x - 343.52202;
            y = 7426928963158243/4611686018427387904 .* x.^3 -1454918324843309/4503599627370496 .* x.^2 + 2735200034075743/140737488355328 .* x -6043303342462469/17592186044416;
        end
        
        function m = Df(~, x) % derivative of f
            m = -5/12 .* x + 35/12;
        end
        function m = Du(~, x) % derivative of u
            m = 0.016048 .* x.^2 -0.544613 .* x + 2.141013;
        end
        function m = Dg(~, x) % derivative of g
            m = 0.004831 .* x.^2 -0.646313 .* x + 19.434765;
        end
        
        function m = DDu(~, x) % derivative of Du
            m = 0.032096 .* x - 0.544613;
        end
        function m = DDg(~, x) % derivative of Dg
            m = 0.009662 .* x - 0.646313;
        end
        
        function radius = radiusCurvature(app, x_pos)
            radius = (1 + (app.Dfn(x_pos)) ^ 2) ^ (3/2) / abs(app.DDfn(x_pos));
        end
        
        function vel = velocitySquared(app, vel_initial, delta_y, delta_x_prime, delta_x, radius_prev, radius)
            %vel = sign(vel_initial) * vel_initial ^ 2 - sign(vel_initial) * 2 * app.gravity * delta_y - 2 * app.gravity * app.uk_coeff * delta_x;
            %vel = (sign(vel_initial) * vel_initial ^ 2 * (1 - (app.uk_coeff / radius_prev) * delta_x_prime) - sign(vel_initial) * 2 * app.gravity * delta_y - 2 * app.gravity * app.uk_coeff * delta_x) / (1 + (app.uk_coeff / radius) * delta_x_prime);
            k_over_m = app.k_coeff / app.mass;
            vel = (sign(vel_initial) * vel_initial ^ 2 * (1 - (app.uk_coeff / radius_prev + k_over_m) * delta_x_prime) - sign(vel_initial) * 2 * app.gravity * delta_y - 2 * app.gravity * app.uk_coeff * delta_x) / (1 + (app.uk_coeff / radius + k_over_m) * delta_x_prime);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stop_animations = 0;
            SHOW_STRUCTURE = false;
            SHOW_ANIMATION = false;
            
            % compute values first
            % then plot with steps
            Y = app.X;
            i = 1;
            for x = app.X
                Y(i) = app.fn(x);
                i = i + 1;
            end
            
            if SHOW_ANIMATION
                if ~SHOW_STRUCTURE
                    fn_animation = animatedline(app.UIAxes, "Color", [0, 0, 0]);
                end
                
                hold(app.UIAxes, "on");
                grey = [0.7 0.7 0.7];
                brown = [0.4 0.2 0];
                step_animation = 5;
                for i = 1:step_animation:length(app.X)-step_animation
                    if SHOW_STRUCTURE
                        area(app.UIAxes, app.X(i:i+step_animation), Y(i:i+step_animation), "FaceColor", grey, "EdgeColor", brown);  
                    else
                        addpoints(fn_animation, app.X(i:i+step_animation), Y(i:i+step_animation));
                    end
                    % prints the derivate of fn at X(i)
                    %text(app.UIAxes, app.X(i), Y(i), sprintf("%f", app.Dfn(app.X(i))));
                    drawnow; 
                end
                if SHOW_STRUCTURE
                    area(app.UIAxes, app.X(end-step_animation:end), Y(end-step_animation:end), "FaceColor", grey, "EdgeColor", brown);
                else
                    addpoints(fn_animation, app.X(end-step_animation:end), Y(end-step_animation:end));
                end
            else
                plot(app.UIAxes, app.X, Y);
            end
            
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            % GUI variables
            initial_velocity = app.VelocidadinicialSpinner.Value;
            app.uk_coeff = app.CoeficientedefriccindinmicoSpinner.Value;
            app.k_coeff = app.CoeficientebarrastreSpinner.Value;
            app.mass = app.MasadelvagnSpinner.Value;
            x_pos = app.XinicialSpinner.Value;
            app.dt = app.dtSpinner.Value;
            app.gravity = app.gravedadSpinner.Value;
            
            if initial_velocity == 0
                initial_velocity = 0.00000000000001; % sign(0) will break the code
            end
            
            X_LIM = app.dt * initial_velocity * 20;
            Y_LIM = app.mass * initial_velocity ^ 2;
            
            % reset GUI
            app.ComenzarButton.Enable = false;
            app.DetenerButton.Enable = true;
            cla(app.UIAxes2);
            
            % initial values for y axis
            y_pos = app.fn(x_pos);
            
            hold(app.UIAxes, "on");
            car_plot = plot(app.UIAxes, x_pos, y_pos, "Marker", "o");
            
            ylim(app.UIAxes2, [-Y_LIM Y_LIM]);
            yticks(app.UIAxes2, -Y_LIM:10:Y_LIM);
            xlim(app.UIAxes2, [0 X_LIM]);
            ke_animation = animatedline(app.UIAxes2, "Color", "blue");
            pe_animation = animatedline(app.UIAxes2, "Color", "red");
            me_animation = animatedline(app.UIAxes2, "Color", "green");
            legend(app.UIAxes2, {'Energía cinética', "Energía potencial", "Energía mecánica"});
            
            time = app.dt;
            
            y_pos_prev = y_pos;
            x_pos_prev = x_pos;
            
            radius = app.radiusCurvature(x_pos);
            radius_prev = radius;
            velocity = initial_velocity;
            velocity_prev = velocity;
            delta_x_prime = 1;
            
            max_x = app.X(end);
            
            MAX_POSITIONS = 1000;
            positions = zeros([MAX_POSITIONS 2]);
            ke_positions = zeros([MAX_POSITIONS 1]);
            pe_positions = zeros([MAX_POSITIONS 1]);
            velocities = zeros([MAX_POSITIONS 1]);
            times = zeros([MAX_POSITIONS 1]);
            
            i = 1;
            while i < MAX_POSITIONS % x_pos < max_x && x_pos > 0
                disp(delta_x_prime);
                slope = app.Dfn(x_pos);
                %fprintf("velocity: %f, velocity_prev: %f\n", velocity, velocity_prev);
                theta = atand(slope); % it will return from -90 to 90
                beta = 90 - abs(theta);
                gravity_x = -sign(theta) * app.gravity * cosd(beta);
                delta_x_prime = abs(velocity * app.dt + 1/2 * gravity_x * (app.dt) ^ 2);
                
                delta_x = sign(velocity) * delta_x_prime * cosd(abs(theta));
                delta_y = sign(velocity) * sign(theta) * delta_x_prime * sind(abs(theta));
                
                %fprintf("delta_x: %f, delta_y: %f\n", delta_x, delta_y);
                
                x_pos = x_pos_prev + delta_x;
                y_pos = app.fn(x_pos);
                
                %delta_y = sign(slope) * abs(y_pos - y_pos_prev);%x_prime * sind(abs(theta));
                %delta_x_prime = x_prime - x_prime_prev;
                %delta_x = x_pos - x_pos_prev;
                %disp(delta_x);
                %delta_x = sign(velocity) * delta_x;
                %fprintf("going_backwards: %i, deltay: %f, deltax: %f, velocity: %f\n", app.going_backwards, delta_y, delta_x, velocity);
                %fprintf("velocity: %f \t gravity_x: %f \t delta_y: %f \t x_pos: %f \t delta_x_prime: %f \t theta: %f \t delta_x: %f\n", velocity, gravity_x, delta_y, x_pos, delta_x_prime, theta, delta_x);
                % compute velocity
                velocity_squared = app.velocitySquared(velocity_prev, delta_y, delta_x_prime, delta_x, radius_prev, radius);
                velocity = sqrt(abs(velocity_squared));
                if velocity_squared < 0
                   velocity = -velocity;
                end
                %fprintf("velocity: %f, velocity2: %f, velocity_prev: %f\n\n", velocity, velocity_squared, velocity_prev);
                %fprintf("velocity: %f \t velocity_prev: %f \t delta_y: %f \t x_pos: %f \t delta_x_prime: %f \t delta_x: %f\n", velocity, velocity_prev, delta_y, x_pos, delta_x_prime, delta_x);
                
                radius = app.radiusCurvature(x_pos);
                
                %fprintf("%i * %f * %f\n\n", -sign(theta), app.gravity, cosd(beta));
                %fprintf("%f + %f * %f + 1/2 * %f * %f\n\n", x_prime_prev, velocity, app.dt, gravity_x, (app.dt) ^ 2);
                
                if x_pos > 0 && x_pos < max_x
                    y_pos_prev = y_pos;
                    velocity_prev = velocity;
                    x_pos_prev = x_pos;
                    radius_prev = radius;
                end

                go_backwards = x_pos > max_x || (velocity_prev > 0 && velocity < 0);
                go_forwards = x_pos < 0 || (velocity_prev < 0 && velocity > 0);
                
                if go_backwards || go_forwards
                    %fprintf("SHOULD GO BACKWARDS: %i FORWARDS: %i\n", go_backwards, go_forwards);
                    pos_x_outside_track = x_pos;
                    x_pos = x_pos_prev;
                    x_pos_prev = pos_x_outside_track;
                    
                    pos_y_outside_track = y_pos;
                    y_pos = y_pos_prev;
                    y_pos_prev = pos_y_outside_track;
                    
                    velocity_outside_track = velocity;
                    velocity = -velocity_prev;
                    velocity_prev = -velocity_outside_track;
                    
                    radius_outside_track = radius;
                    radius = radius_prev;
                    radius_prev = radius_outside_track;
                end
                
                time = time + app.dt;
                ke = 1 / 2 * app.mass * velocity ^ 2;
                pe = app.mass * app.gravity * y_pos;
                
                positions(i, 1) = x_pos;
                positions(i, 2) = y_pos;
                ke_positions(i, 1) = ke;
                pe_positions(i, 1) = pe;
                velocities(i, 1) = velocity;
                times(i, 1) = time;
                %positions(i) = [x_pos y_pos];
                %ke_positions(i) = [time ke];
                %pe_positions(i) = [time pe];
                
                %addpoints(ke_animation, time, ke);
                %addpoints(pe_animation, time, pe);
                %addpoints(me_animation, time, ke + pe);
                
                %car_plot.XData = x_pos;
                %car_plot.YData = y_pos + 1; % +1 to plot the point OVER the road, NOT in the road 
                %app.TimeLabel.Text = sprintf("%0.2fs %0.2f m/s", time, velocity);
                %pause(app.dt);
                
                i = i + 1;
            end
            
            i = 1;
            while i < MAX_POSITIONS
                %disp(i);
                %time = ke_positions(i, 1);
                addpoints(ke_animation, i, ke_positions(i, 1));
                addpoints(pe_animation, i, pe_positions(i, 1));
                addpoints(me_animation, i, ke_positions(i, 1) + pe_positions(i, 1));
                
                car_plot.XData = positions(i, 1);
                car_plot.YData = positions(i, 2) + 1; % +1 to plot the point OVER the road, NOT in the road 
                
                app.TimeLabel.Text = sprintf("%0.2fs %0.2f m/s", times(i, 1), velocities(i, 1));
                
                i = i + 1;
                
                pause(0.1);
            end
            
            app.stop_animations = false;
            app.DetenerButton.Text = "Detener";
            app.DetenerButton.Enable = false;
            app.ComenzarButton.Enable = true;
        end

        % Callback function
        function LimpiargrficaButtonPushed(app, event)
            app.UIAxes.cla;
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.DetenerButton.Text = "Espere...";
            app.DetenerButton.Enable = "off";
            app.stop_animations = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.VelocidadinicialSpinnerLabel = uilabel(app.GridLayout2);
            app.VelocidadinicialSpinnerLabel.Text = 'Velocidad inicial';
            app.VelocidadinicialSpinner = uispinner(app.GridLayout2);
            app.VelocidadinicialSpinner.Limits = [0 1000];
            app.VelocidadinicialSpinner.Value = 60;
            app.MasadelvagnSpinner_2Label = uilabel(app.GridLayout2);
            app.MasadelvagnSpinner_2Label.Text = 'Masa del vagón';
            app.MasadelvagnSpinner = uispinner(app.GridLayout2);
            app.MasadelvagnSpinner.Limits = [0 100000];
            app.MasadelvagnSpinner.Value = 200;
            app.CoeficientedefriccindinmicoSpinnerLabel = uilabel(app.GridLayout2);
            app.CoeficientedefriccindinmicoSpinnerLabel.Text = 'Coeficiente de fricción dinámico';
            app.CoeficientedefriccindinmicoSpinner = uispinner(app.GridLayout2);
            app.CoeficientedefriccindinmicoSpinner.Limits = [0 25];
            app.CoeficientedefriccindinmicoSpinner.Value = 0.1;
            app.ValoresLabel = uilabel(app.GridLayout2);
            app.ValoresLabel.Text = 'Valores';
            app.UnidadesLabel = uilabel(app.GridLayout2);
            app.UnidadesLabel.Text = 'Unidades';
            app.CoeficientebarrastreSpinnerLabel = uilabel(app.GridLayout2);
            app.CoeficientebarrastreSpinnerLabel.Text = 'Coeficiente b (arrastre)';
            app.CoeficientebarrastreSpinner = uispinner(app.GridLayout2);
            app.CoeficientebarrastreSpinner.Step = 0.05;
            app.CoeficientebarrastreSpinner.Limits = [0 2];
            app.CoeficientebarrastreSpinner.Value = 0.1;
            app.msLabel = uilabel(app.GridLayout2);
            app.msLabel.Text = 'm/s';
            app.kilogramosLabel_2 = uilabel(app.GridLayout2);
            app.kilogramosLabel_2.Text = 'kilogramos';
            app.ComenzarButton = uibutton(app.GridLayout2, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Vagón en la montaña rusa')
            xlabel(app.UIAxes, 'Longitud (x) m')
            ylabel(app.UIAxes, 'Altura (y) m')
            app.UIAxes.XLim = [0 55];
            app.UIAxes.YLim = [0 45];
            app.UIAxes.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54];
            app.DetenerButton = uibutton(app.GridLayout2, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Enable = 'off';
            app.DetenerButton.Text = 'Detener';
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Tiempo (s)')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.dtSpinnerLabel = uilabel(app.GridLayout2);
            app.dtSpinnerLabel.Text = 'dt';
            app.dtSpinner = uispinner(app.GridLayout2);
            app.dtSpinner.Step = 0.1;
            app.dtSpinner.Limits = [0 2];
            app.dtSpinner.Value = 0.08;
            app.gravedadSpinnerLabel = uilabel(app.GridLayout2);
            app.gravedadSpinnerLabel.Text = 'gravedad';
            app.gravedadSpinner = uispinner(app.GridLayout2);
            app.gravedadSpinner.Limits = [0 100];
            app.gravedadSpinner.Value = 9.81;
            app.segundosLabel = uilabel(app.GridLayout2);
            app.segundosLabel.Text = 'segundos';
            app.ms2Label = uilabel(app.GridLayout2);
            app.ms2Label.Text = 'm/(s^2)';
            app.XinicialSpinnerLabel = uilabel(app.GridLayout2);
            app.XinicialSpinnerLabel.Text = 'X inicial';
            app.XinicialSpinner = uispinner(app.GridLayout2);
            app.metrosLabel = uilabel(app.GridLayout2);
            app.metrosLabel.Text = 'metros';
            app.TimeLabel = uilabel(app.GridLayout2);
            app.TimeLabel.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_optimized

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end